classdef apParcial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShazamAlakazamLabel     matlab.ui.control.Label
        AlakazameaButton        matlab.ui.control.Button
        ShazameaEditField       matlab.ui.control.EditField
        ShazameaEditFieldLabel  matlab.ui.control.Label
        PontucancionButton      matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cancionNombre % Nombre de la cancion escogtida por el usuario
        cancionInfo % Info de la cancion puesta por el usuario 
        cancionInput
        FsInput

        cancion1
        cancion1Info
        Fs1
        cancion2 
        cancion2Info
        Fs2

        canciones

        desfaseTiempo
        cancion
    end
    
    methods (Access = private)
        function setDatabase(app) %TEMPORAL
            [app.cancion1,app.Fs1] = audioread("Melody3.mp3");
            app.cancion1Info = audioinfo("Melody3.mp3");

            [app.cancion2,app.Fs2] = audioread("takeTrain.mp3");
            app.cancion2Info = audioinfo("takeTrain.mp3");

            sum = 0;
            for i = 1 : 1 : app.cancion1Info.NumChannels
                    sum = sum + app.cancion1(:,i);
            end
            app.cancion1 = sum/app.cancion1Info.NumChannels;

            sum = 0;

            for i = 1 : 1 : app.cancion2Info.NumChannels
                    sum = sum + app.cancion2(:,i);
            end
                
            app.cancion2 = sum/app.cancion2Info.NumChannels;

            app.canciones.a = app.cancion1
            app.canciones.b = app.cancion2

        end
        
        function setInputSong(app)
            app.cancionInfo = audioinfo(app.cancionNombre);
            [app.cancionInput,app.FsInput] = audioread(app.cancionNombre);

            if (app.cancionInfo.NumChannels ~= 1)
                sum = 0;

                for i = 1 : 1 : app.cancionInfo.NumChannels
                    sum = sum + app.cancionInput(:,i);
                end
                
                app.cancionInput = sum/app.cancionInfo.NumChannels;
            end

            
        end

        function plotInputSong(app)
            t = 0:seconds(1/app.FsInput):seconds(app.cancionInfo.Duration);
            t = t(1:end-1);
            plot(app.UIAxes, t,app.cancionInput);
            title(app.UIAxes, app.cancionNombre)
        end

        function [cor, lag] = getCor(app)

            fn = fieldnames(app.canciones);

            [cor, lag] = xcorr(app.canciones.(fn{1}) ,app.cancionInput);

            for i=1:numel(fn)
                song = app.canciones.(fn{i});
                [c,l] = xcorr(song,app.cancionInput);  
                
                if (max(abs(c)) > max(abs(cor)))
                    cor = c;
                    lag = l;
                end
            end
            return 
        end

            
        function timeDiff = getTimeDiff(app, cor, lag)
            [~,I] = max(abs(cor));
            SampleDiff = lag(I);
            timeDiff = SampleDiff/app.Fs1;
            return
        end

        
        function compareSongs(app)
            [cor, lag] = getCor(app);
            timeDiff = getTimeDiff(app, cor, lag);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PontucancionButton
        function PontucancionButtonPushed(app, event)
            [filename, path] = uigetfile('*.mp3');
            if (filename ~= 0)
                app.ShazameaEditField.Value = filename;
                app.cancionNombre = filename;
                figure(app.UIFigure);
                app.AlakazameaButton.Visible = "on";
            end 
        end

        % Value changed function: ShazameaEditField
        function ShazameaEditFieldValueChanged(app, event)
            value = app.ShazameaEditField.Value;
            
        end

        % Button pushed function: AlakazameaButton
        function AlakazameaButtonPushed(app, event)
            app.setInputSong();
            app.setDatabase(); %TEMPORAL
            app.plotInputSong();
            app.compareSongs();
            app.AlakazameaButton.Visible = "off";
            app.UIAxes.Visible = "on";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'app.cancionNombre')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Señal de audio')
            zlabel(app.UIAxes, 'Tiempo')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.PontucancionButton = uibutton(app.UIFigure, 'push');
            app.PontucancionButton.ButtonPushedFcn = createCallbackFcn(app, @PontucancionButtonPushed, true);
            app.PontucancionButton.Text = 'Pon tu cancion!';
            app.ShazameaEditFieldLabel = uilabel(app.UIFigure);
            app.ShazameaEditFieldLabel.Text = 'Shazamea!';
            app.ShazameaEditField = uieditfield(app.UIFigure, 'text');
            app.ShazameaEditField.ValueChangedFcn = createCallbackFcn(app, @ShazameaEditFieldValueChanged, true);
            app.AlakazameaButton = uibutton(app.UIFigure, 'push');
            app.AlakazameaButton.ButtonPushedFcn = createCallbackFcn(app, @AlakazameaButtonPushed, true);
            app.AlakazameaButton.Visible = 'off';
            app.AlakazameaButton.Text = 'Alakazamea';
            app.ShazamAlakazamLabel = uilabel(app.UIFigure);
            app.ShazamAlakazamLabel.Text = 'S̶h̶a̶z̶a̶m̶  Alakazam';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apParcial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end